classdef Proyecto_ETS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TriangularButton           matlab.ui.control.Button
        RectangularButton          matlab.ui.control.Button
        VentanaLabel               matlab.ui.control.Label
        FIRLabel                   matlab.ui.control.Label
        ImportarAudioButton        matlab.ui.control.Button
        MostarSealesButton         matlab.ui.control.Button
        RubioMartnezSusanaProcesamientoDeSealesLabel  matlab.ui.control.Label
        RechazaBandaButton         matlab.ui.control.Button
        PasaBandaButton            matlab.ui.control.Button
        PasaBajasButton            matlab.ui.control.Button
        PasaAltasButton            matlab.ui.control.Button
        AplicarTransformadaButton  matlab.ui.control.Button
        SumarsealesButton          matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        UIAxes11                   matlab.ui.control.UIAxes
        UIAxes9                    matlab.ui.control.UIAxes
        UIAxes8                    matlab.ui.control.UIAxes
        UIAxes7                    matlab.ui.control.UIAxes
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MostarSealesButton
        function MostarSealesButtonPushed(app, event)
            % Parámetros de la señal
            frecuencia_original_50 = 50; % Frecuencia de la señal original en Hz
            frecuencia_original_100 = 100; % Frecuencia de la señal original en Hz
            frecuencia_original_200 = 200; % Frecuencia de la señal original en Hz
            frecuencia_original_400 = 400; % Frecuencia de la señal original en Hz
            frecuencia_original_800 = 800; % Frecuencia de la señal original en Hz
            frecuencia_original_1600 = 1600; % Frecuencia de la señal original en Hz
            frecuencia_original_3200 = 3200; % Frecuencia de la señal original en Hz
            frecuencia_original_6400 = 6400; % Frecuencia de la señal original en Hz
            
            frecuencia_muestreo = 44100; % Frecuencia de muestreo Fs
            
            duracion = 1; % Duración de la señal en segundos
            t = 0:(1/frecuencia_muestreo):duracion; % Vector de tiempo con la frecuencia de muestreo adecuada
            senal_original_50 = sin(2*pi*frecuencia_original_50*t);          
            senal_original_100 = sin(2*pi*frecuencia_original_100*t);          
            senal_original_200 = sin(2*pi*frecuencia_original_200*t);          
            senal_original_400 = sin(2*pi*frecuencia_original_400*t);          
            senal_original_800 = sin(2*pi*frecuencia_original_800*t);          
            senal_original_1600 = sin(2*pi*frecuencia_original_1600*t);          
            senal_original_3200 = sin(2*pi*frecuencia_original_3200*t);          
            senal_original_6400 = sin(2*pi*frecuencia_original_6400*t);
            
            % Mostrar la señal original en el UIAxes
            plot(app.UIAxes, t, senal_original_50);
            plot(app.UIAxes2, t, senal_original_100);
            plot(app.UIAxes3, t, senal_original_200);
            plot(app.UIAxes4, t, senal_original_400);
            plot(app.UIAxes5, t, senal_original_800);
            plot(app.UIAxes6, t, senal_original_1600);
            plot(app.UIAxes7, t, senal_original_3200);
            plot(app.UIAxes8, t, senal_original_6400);

            % Sumar las señales

            senal_suma = zeros(size(t)); % Inicializar señal suma
            senal_suma = senal_original_50 + senal_original_100 + senal_original_200 + senal_original_400 + senal_original_800 + senal_original_1600 + senal_original_3200 + senal_original_6400;
            app.UIFigure.UserData = senal_suma;
           
        end

        % Button pushed function: SumarsealesButton
        function SumarsealesButtonPushed(app, event)
            senal_suma = app.UIFigure.UserData;
            
            %t = linspace(0, duracion, numel(senal_suma));
            frecuencia_muestreo = 44100;
            duracion = 1; % Duración de la señal en segundos
            t = 0:(1/frecuencia_muestreo):duracion; % Vector de tiempo con la frecuencia de muestreo adecuada
            plot(app.UIAxes9, t, senal_suma, 'y');
        end

        % Button pushed function: RectangularButton
        function RectangularButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            % Aplicar ventana rectangular
            ventana_rectangular = ones(size(senal_original)); % Crear una ventana rectangular del mismo tamaño que la señal
            senal_ventaneada = senal_original .* ventana_rectangular; % Multiplicar elemento por elemento
            frecuencia_muestreo = 44100;
            duracion = 1; % Duración de la señal en segundos
            t = 0:(1/frecuencia_muestreo):duracion; % Vector de tiempo con la frecuencia de muestreo adecuada
            plot(app.UIAxes11, t, senal_ventaneada, 'r');
            xlabel(app.UIAxes11,'Tiempo');
            ylabel(app.UIAxes11,'Amplitud');
            title(app.UIAxes11,'Señal Ventaneada (Rectangular)');
            
            
        end

        % Button pushed function: TriangularButton
        function TriangularButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            % Aplicar ventana rectangular
            % Crear la ventana triangular del mismo tamaño que la señal
            N = length(senal_original);
            ventana_triangular = 1 - abs((0:N-1)-(N-1)/2) / ((N-1)/2);

            senal_ventaneada = senal_original .* ventana_triangular; % Multiplicar elemento por elemento
            frecuencia_muestreo = 44100;
            duracion = 1; % Duración de la señal en segundos
            t = 0:(1/frecuencia_muestreo):duracion; % Vector de tiempo con la frecuencia de muestreo adecuada
            
            plot(app.UIAxes11, t, senal_ventaneada, 'g');
            xlabel(app.UIAxes11,'Tiempo');
            ylabel(app.UIAxes11,'Amplitud');
            title(app.UIAxes11,'Señal Ventaneada (triangular)');
               
        end

        % Callback function: not associated with a component
        function HammingButtonPushed(app, event)
            
            senal_original = app.UIFigure.UserData;
            % Crear la ventana de Hamming del mismo tamaño que la señal
            N = length(senal_original);
            ventana_hamming = 0.54 - 0.46 * (cos(2 * pi * (0:N-1) / (N-1)));
        
            % Aplicar la ventana de Hamming a la señal original
            senal_ventaneada = senal_original .* ventana_hamming';
            frecuencia_muestreo = 44100;
            duracion = 1; % Duración de la señal en segundos
            t = 0:(1/frecuencia_muestreo):duracion; % Vector de tiempo con la frecuencia de muestreo adecuada
            
            plot(app.UIAxes11, t, senal_ventaneada);
            figure;
            plot(t, senal_ventaneada);
            xlabel('Tiempo');
            ylabel('Amplitud');
            title('Señal Ventaneada (triangular)');
        end

        % Callback function: not associated with a component
        function KaiserButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            N = length(senal_original);
            beta = 4; % Parámetro de forma
            ventana = besseli(0, beta * sqrt(1 - ((2*(0:N-1))/(N-1) - 1).^2)) / besseli(0, beta);
            % Aplicar ventana a la señal senoidal
            senal_ventaneada = senal_original .* ventana';
            plot(app.UIAxes11, t, senal_ventaneada);
            figure;
            plot(t, senal_ventaneada);
            xlabel('Tiempo');
            ylabel('Amplitud');
            title('Señal Ventaneada (Kaiser)');
        end

        % Callback function: not associated with a component
        function BlackmanButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            N = length(senal_original); % Tamaño de la ventana
            a0 = 0.42;
            a1 = 0.5;
            a2 = 0.08;
            
            n = 0:N-1;
            ventana = a0 - a1*cos(2*pi*n/(N-1)) + a2*cos(4*pi*n/(N-1));
            % Aplicar ventana a la señal senoidal
            senal_ventaneada = senal_original .* ventana';
            plot(app.UIAxes11, t, senal_ventaneada);
            xlabel(app.UIAxes10,'Tiempo');
            ylabel(app.UIAxes10,'Amplitud');
            title(app.UIAxes10,'Señal Ventaneada (Blackman)');
        end

        % Button pushed function: AplicarTransformadaButton
        function AplicarTransformadaButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            % Aplicar la Transformada Rápida de Fourier
            transformada = fft(senal_original);
            
            % Obtener el espectro de magnitud
            espectro_magnitud = abs(transformada);
            frecuencia_muestreo = 44100;
            % Crear el vector de frecuencias correspondiente
            frecuencias = linspace(0, frecuencia_muestreo, length(senal_original));
            
            % Mostrar el espectro de magnitud
            plot(app.UIAxes11, frecuencias, espectro_magnitud, 'c');
            xlabel(app.UIAxes11, 'Frecuencia (Hz)');
            ylabel(app.UIAxes11,'Magnitud');
            title(app.UIAxes11,'Espectro de Magnitud de la Señal Senoidal');
        end

        % Button pushed function: PasaAltasButton
        function PasaAltasButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            % Parámetros del filtro
            frecuencia_corte = 500; % Frecuencia de corte en Hz
            frecuencia_muestreo = 44100; % Frecuencia de muestreo en Hz
            t = 0:1/frecuencia_muestreo:1;
        
            % Coeficientes del filtro pasa altas
            orden = 100; % Orden del filtro
            frecuencia_corte_norm = frecuencia_corte / (frecuencia_muestreo / 2);
            coeficientes = zeros(1, orden+1);
            for k = -orden/2:orden/2
                if k == 0
                    coeficientes(k+orden/2+1) = 1 - 2*frecuencia_corte_norm;
                else
                    coeficientes(k+orden/2+1) = -sin(2*pi*frecuencia_corte_norm*k) / (pi*k);
                end
            end
            
            % Aplicar el filtro pasa altas
            senal_filtrada_altas = conv(senal_original , coeficientes, 'same');
            
            % Aplicar la transformada de Fourier
            transformada_1 = fft(senal_original);
            transformada_2 = fft(senal_filtrada_altas);
            % Obtener los valores de frecuencia correspondientes
            frecuencias = linspace(0, frecuencia_muestreo, length(t));
            % Mostrar la señal original y la señal filtrada en un gráfico
            
            plot(app.UIAxes11, t, senal_filtrada_altas, 'm');
            xlabel(app.UIAxes11, 'Tiempo');
            ylabel(app.UIAxes11, 'Amplitud');
            title(app.UIAxes11, 'Filtro Pasa Altas');

            subplot(2,2,1)
            plot(t, senal_original, 'y', t, senal_filtrada_altas, 'm');
            xlabel('Tiempo');
            ylabel('Amplitud');
            legend('Señal Original', 'Señal Filtrada (Pasa altas)');
            title('Filtro Pasa Banda');
            
            subplot(2, 2, 2);
            plot(frecuencias, abs(transformada_1), 'y', frecuencias, abs(transformada_2), 'm');
            title('Espectro de frecuencia');
            legend('Señal Original', 'Señal Filtrada (Pasa altas)');
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');

        end

        % Button pushed function: PasaBajasButton
        function PasaBajasButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            % Parámetros del filtro
            frecuencia_corte = 1000; % Frecuencia de corte en Hz
            frecuencia_muestreo = 44100; % Frecuencia de muestreo en Hz
           
            % Coeficientes del filtro pasa bajas
            orden = 100; % Orden del filtro
            frecuencia_corte_norm = frecuencia_corte / (frecuencia_muestreo / 2);
            coeficientes = zeros(1, orden+1);
            for k = -orden/2:orden/2
                if k == 0
                    coeficientes(k+orden/2+1) = 2*frecuencia_corte_norm;
                else
                    coeficientes(k+orden/2+1) = sin(2*pi*frecuencia_corte_norm*k) / (pi*k);
                end
            end
            t = 0:1/frecuencia_muestreo:1;            
            % Aplicar el filtro pasa bajas
            senal_filtrada_bajas = conv(senal_original, coeficientes, 'same');
            
            % Aplicar la transformada de Fourier
            transformada_1 = fft(senal_original);
            transformada_2 = fft(senal_filtrada_bajas);
            % Obtener los valores de frecuencia correspondientes
            frecuencias = linspace(0, frecuencia_muestreo, length(t));
            % Mostrar la señal original y la señal filtrada en un gráfico
        
            plot(app.UIAxes11, t, senal_filtrada_bajas, 'r');
            xlabel(app.UIAxes11, 'Tiempo');
            ylabel(app.UIAxes11, 'Amplitud');
            title(app.UIAxes11, 'Filtro Pasa Bajas');

            subplot(2,2,1)
            plot(t, senal_original, 'y', t, senal_filtrada_bajas, 'r');
            xlabel('Tiempo');
            ylabel('Amplitud');
            legend('Señal Original', 'Señal Filtrada (Pasa bajas)');
            title('Filtro Pasa Banda');
            
            subplot(2, 2, 2);
            plot(frecuencias, abs(transformada_1), 'y', frecuencias, abs(transformada_2), 'r');
            title('Espectro de frecuencia');
            legend('Señal Original', 'Señal Filtrada (Pasa bajas)');
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');

            
        end

        % Button pushed function: PasaBandaButton
        function PasaBandaButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            % Parámetros del filtro
            frecuencia_corte_bajas = 500; % Frecuencia de corte inferior en Hz
            frecuencia_corte_altas = 2000; % Frecuencia de corte superior en Hz
            frecuencia_muestreo = 44100; % Frecuencia de muestreo en Hz
            
            % Señal de ejemplo
            t = 0:1/frecuencia_muestreo:1;           
            % Coeficientes del filtro pasa bajas
            orden_bajas = 100; % Orden del filtro pasa bajas
            frecuencia_corte_bajas_norm = frecuencia_corte_bajas / (frecuencia_muestreo / 2);
            coeficientes_bajas = zeros(1, orden_bajas+1);
            for k = -orden_bajas/2:orden_bajas/2
                if k == 0
                    coeficientes_bajas(k+orden_bajas/2+1) = 2*frecuencia_corte_bajas_norm;
                else
                    coeficientes_bajas(k+orden_bajas/2+1) = sin(2*pi*frecuencia_corte_bajas_norm*k) / (pi*k);
                end
            end
            
            % Coeficientes del filtro pasa altas
            orden_altas = 100; % Orden del filtro pasa altas
            frecuencia_corte_altas_norm = frecuencia_corte_altas / (frecuencia_muestreo / 2);
            coeficientes_altas = zeros(1, orden_altas+1);
            for k = -orden_altas/2:orden_altas/2
                if k == 0
                    coeficientes_altas(k+orden_altas/2+1) = 1 - 2*frecuencia_corte_altas_norm;
                else
                    coeficientes_altas(k+orden_altas/2+1) = -sin(2*pi*frecuencia_corte_altas_norm*k) / (pi*k);
                end
            end
            
            % Aplicar el filtro pasa bajas
            senal_filtrada_bajas = conv(senal_original, coeficientes_bajas, 'same');
            
            % Aplicar el filtro pasa altas
            senal_filtrada_altas = conv(senal_original, coeficientes_altas, 'same');
            
            % Señal filtrada pasa banda
            senal_filtrada_pasa_banda = senal_filtrada_bajas - senal_filtrada_altas;
            
            % Aplicar la transformada de Fourier
            transformada_1 = fft(senal_original);
            transformada_2 = fft(senal_filtrada_pasa_banda);
            % Obtener los valores de frecuencia correspondientes
            frecuencias = linspace(0, frecuencia_muestreo, length(t));
            % Mostrar la señal original y la señal filtrada en un gráfico

            plot(app.UIAxes11, t, senal_filtrada_pasa_banda, 'g');
            xlabel(app.UIAxes11, 'Tiempo');
            ylabel(app.UIAxes11, 'Amplitud');
            title(app.UIAxes11, 'Filtro Pasa Banda');

            subplot(2,2,1)
            plot(t, senal_original, 'y', t, senal_filtrada_pasa_banda, 'g');
            xlabel('Tiempo');
            ylabel('Amplitud');
            legend('Señal Original', 'Señal Filtrada (Pasa Banda)');
            title('Filtro Pasa Banda');
            
            subplot(2, 2, 2);
            plot(frecuencias, abs(transformada_1), 'y', frecuencias, abs(transformada_2), 'g');
            title('Espectro de frecuencia');
            legend('Señal Original', 'Señal Filtrada (Pasa Banda)');
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');


        end

        % Button pushed function: RechazaBandaButton
        function RechazaBandaButtonPushed(app, event)
            senal_original = app.UIFigure.UserData;
            % Parámetros del filtro
            frecuencia_corte_bajas = 500; % Frecuencia de corte inferior en Hz
            frecuencia_corte_altas = 2000; % Frecuencia de corte superior en Hz
            frecuencia_muestreo = 44100; % Frecuencia de muestreo en Hz
            
            % Señal de ejemplo
            t = 0:1/frecuencia_muestreo:1;           
            % Coeficientes del filtro pasa bajas
            orden_bajas = 100; % Orden del filtro pasa bajas
            frecuencia_corte_bajas_norm = frecuencia_corte_bajas / (frecuencia_muestreo / 2);
            coeficientes_bajas = zeros(1, orden_bajas+1);
            for k = -orden_bajas/2:orden_bajas/2
                if k == 0
                    coeficientes_bajas(k+orden_bajas/2+1) = 2*frecuencia_corte_bajas_norm;
                else
                    coeficientes_bajas(k+orden_bajas/2+1) = sin(2*pi*frecuencia_corte_bajas_norm*k) / (pi*k);
                end
            end
            
            % Coeficientes del filtro pasa altas
            orden_altas = 100; % Orden del filtro pasa altas
            frecuencia_corte_altas_norm = frecuencia_corte_altas / (frecuencia_muestreo / 2);
            coeficientes_altas = zeros(1, orden_altas+1);
            for k = -orden_altas/2:orden_altas/2
                if k == 0
                    coeficientes_altas(k+orden_altas/2+1) = 1 - 2*frecuencia_corte_altas_norm;
                else
                    coeficientes_altas(k+orden_altas/2+1) = -sin(2*pi*frecuencia_corte_altas_norm*k) / (pi*k);
                end
            end
            
            % Aplicar el filtro pasa bajas
            senal_filtrada_bajas = conv(senal_original, coeficientes_bajas, 'same');
            
            % Aplicar el filtro pasa altas
            senal_filtrada_altas = conv(senal_original, coeficientes_altas, 'same');
            
            % Señal filtrada pasa banda
            senal_filtrada_rechaza_banda = senal_original - senal_filtrada_bajas - senal_filtrada_altas;
            
            % Aplicar la transformada de Fourier
            transformada_1 = fft(senal_original);
            transformada_2 = fft(senal_filtrada_rechaza_banda);
            % Obtener los valores de frecuencia correspondientes
            frecuencias = linspace(0, frecuencia_muestreo, length(t));
            % Mostrar la señal original y la señal filtrada en un gráfico

            plot(app.UIAxes11, t, senal_filtrada_rechaza_banda, 'c');
            xlabel(app.UIAxes11, 'Tiempo');
            ylabel(app.UIAxes11, 'Amplitud');
            title(app.UIAxes11, 'Filtro Rechaza Banda');

            subplot(2,2,1)
            plot(t, senal_original, 'y', t, senal_filtrada_rechaza_banda, 'c');
            xlabel('Tiempo');
            ylabel('Amplitud');
            legend('Señal Original', 'Señal Filtrada (Rechaza Banda)');
            title('Filtro Pasa Banda');
            
            subplot(2, 2, 2);
            plot(frecuencias, abs(transformada_1), 'y', frecuencias, abs(transformada_2), 'c');
            title('Espectro de frecuencia');
            legend('Señal Original', 'Señal Filtrada (Rechaza Banda)');
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');

        end

        % Button pushed function: ImportarAudioButton
        function ImportarAudioButtonPushed(app, event)
                    
            [nombre_archivo, ruta] = uigetfile({'*.mp3';'*.wav'}, 'Selecciona un archivo de audio');
            
            if nombre_archivo ~= 0
                archivo_audio = fullfile(ruta, nombre_archivo);
                
                [audio, fs_actual] = audioread(archivo_audio); % Leer el archivo de audio original

                nuevo_archivo_audio = 'nuevo_audio.mp3'; % Nombre del nuevo archivo de audio
                fs_deseada = 44100; % Frecuencia de muestreo deseada
                
                % Calcular el factor de cambio de frecuencia
                factor_cambio_fs = fs_deseada / fs_actual;
                
                % Calcular la nueva longitud de la señal
                longitud_nueva = round(length(audio) * factor_cambio_fs);
                
                % Interpolar la señal utilizando el método de interpolación más cercano
                audio_resampleado = interp1(1:length(audio), audio, linspace(1, length(audio), longitud_nueva), 'nearest');
                audiowrite(nuevo_archivo_audio, audio_resampleado, fs_deseada); % Guardar el nuevo archivo de audio con la frecuencia de muestreo deseada
                
                % Acortar la duración a 1 segundo
                [audio1, fs_actual] = audioread("nuevo_audio.mp3"); % Leer el archivo de audio con fs modificada
                duracion_deseada = 1; % Duración deseada en segundos
                nueva_longitud = round(fs_actual * duracion_deseada); % Nueva longitud de la señal
                
                audio_acortado = audio1(1:nueva_longitud, :); % Acortar la señal al nuevo tamaño

                app.UIFigure.UserData = audio_acortado;
                % Muestra la señal de audio en un componente UIAxes
                
                plot(app.UIAxes9, audio_acortado, 'g');
                xlabel(app.UIAxes9, 'Tiempo');
                ylabel(app.UIAxes9, 'Amplitud');
                title(app.UIAxes9, 'Señal de Audio con fs=44100' );
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {596, 596};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {133, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SumarsealesButton = uibutton(app.LeftPanel, 'push');
            app.SumarsealesButton.ButtonPushedFcn = createCallbackFcn(app, @SumarsealesButtonPushed, true);
            app.SumarsealesButton.Text = 'Sumar señales';
            app.AplicarTransformadaButton = uibutton(app.LeftPanel, 'push');
            app.AplicarTransformadaButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarTransformadaButtonPushed, true);
            app.AplicarTransformadaButton.Text = {'Aplicar '; 'Transformada'};
            app.PasaAltasButton = uibutton(app.LeftPanel, 'push');
            app.PasaAltasButton.ButtonPushedFcn = createCallbackFcn(app, @PasaAltasButtonPushed, true);
            app.PasaAltasButton.Text = 'Pasa-Altas';
            app.PasaBajasButton = uibutton(app.LeftPanel, 'push');
            app.PasaBajasButton.ButtonPushedFcn = createCallbackFcn(app, @PasaBajasButtonPushed, true);
            app.PasaBajasButton.Text = 'Pasa-Bajas';
            app.PasaBandaButton = uibutton(app.LeftPanel, 'push');
            app.PasaBandaButton.ButtonPushedFcn = createCallbackFcn(app, @PasaBandaButtonPushed, true);
            app.PasaBandaButton.Text = 'Pasa-Banda';
            app.RechazaBandaButton = uibutton(app.LeftPanel, 'push');
            app.RechazaBandaButton.ButtonPushedFcn = createCallbackFcn(app, @RechazaBandaButtonPushed, true);
            app.RechazaBandaButton.Text = 'Rechaza-Banda';
            app.RubioMartnezSusanaProcesamientoDeSealesLabel = uilabel(app.LeftPanel);
            app.RubioMartnezSusanaProcesamientoDeSealesLabel.Text = {'Rubio '; 'Martínez '; 'Susana'; ''; 'Procesamiento'; 'De Señales'};
            app.MostarSealesButton = uibutton(app.LeftPanel, 'push');
            app.MostarSealesButton.ButtonPushedFcn = createCallbackFcn(app, @MostarSealesButtonPushed, true);
            app.MostarSealesButton.Text = 'Mostar Señales';
            app.ImportarAudioButton = uibutton(app.LeftPanel, 'push');
            app.ImportarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarAudioButtonPushed, true);
            app.ImportarAudioButton.Text = 'Importar Audio';
            app.FIRLabel = uilabel(app.LeftPanel);
            app.FIRLabel.Text = 'FIR';
            app.VentanaLabel = uilabel(app.LeftPanel);
            app.VentanaLabel.Text = 'Ventana';
            app.RectangularButton = uibutton(app.LeftPanel, 'push');
            app.RectangularButton.ButtonPushedFcn = createCallbackFcn(app, @RectangularButtonPushed, true);
            app.RectangularButton.Text = 'Rectangular';
            app.TriangularButton = uibutton(app.LeftPanel, 'push');
            app.TriangularButton.ButtonPushedFcn = createCallbackFcn(app, @TriangularButtonPushed, true);
            app.TriangularButton.Text = 'Triangular';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Proyecto';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Señal senoidal de 50Hz')
            xlabel(app.UIAxes, 'Periodo (T)')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'hot')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Señal senoidal de 100Hz')
            xlabel(app.UIAxes2, 'Periodo (T)')
            ylabel(app.UIAxes2, 'Amplitud')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'summer')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Señal senoidal de 200Hz')
            xlabel(app.UIAxes3, 'Periodo (T)')
            ylabel(app.UIAxes3, 'Amplitud')
            zlabel(app.UIAxes3, 'Z')
            colormap(app.UIAxes3, 'summer')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Señal senoidal de 400Hz')
            xlabel(app.UIAxes4, 'Periodo (T)')
            ylabel(app.UIAxes4, 'Amplitud')
            zlabel(app.UIAxes4, 'Z')
            colormap(app.UIAxes4, 'summer')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Señal senoidal 800Hz')
            xlabel(app.UIAxes5, 'Periodo (T)')
            ylabel(app.UIAxes5, 'Amplitud')
            zlabel(app.UIAxes5, 'Z')
            colormap(app.UIAxes5, 'summer')
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, 'Señal senoidal 1600Hz')
            xlabel(app.UIAxes6, 'Periodo (T)')
            ylabel(app.UIAxes6, 'Amplitud')
            zlabel(app.UIAxes6, 'Z')
            colormap(app.UIAxes6, 'summer')
            app.UIAxes7 = uiaxes(app.RightPanel);
            title(app.UIAxes7, 'Señal senoidal 3200Hz')
            xlabel(app.UIAxes7, 'Periodo (T)')
            ylabel(app.UIAxes7, 'Amplitud')
            zlabel(app.UIAxes7, 'Z')
            colormap(app.UIAxes7, 'summer')
            app.UIAxes8 = uiaxes(app.RightPanel);
            title(app.UIAxes8, 'Señal senoidal 6400Hz')
            xlabel(app.UIAxes8, 'Periodo (T)')
            ylabel(app.UIAxes8, 'Amplitud')
            zlabel(app.UIAxes8, 'Z')
            colormap(app.UIAxes8, 'summer')
            app.UIAxes9 = uiaxes(app.RightPanel);
            title(app.UIAxes9, 'Señal original')
            xlabel(app.UIAxes9, 'Perido (T)')
            ylabel(app.UIAxes9, 'Amplitud')
            zlabel(app.UIAxes9, 'Z')
            colormap(app.UIAxes9, 'summer')
            app.UIAxes11 = uiaxes(app.RightPanel);
            title(app.UIAxes11, 'Señal Resultante')
            xlabel(app.UIAxes11, 'Periodo (T)')
            ylabel(app.UIAxes11, 'Amplitud')
            zlabel(app.UIAxes11, 'Z')
            colormap(app.UIAxes11, 'summer')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto_ETS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end